classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ExportButton  matlab.ui.control.Button
        LoadButton    matlab.ui.control.Button
        Slider        matlab.ui.control.Slider
        SliderLabel   matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        smoothedData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExportButton, LoadButton
        function LoadButtonPushed(app, event)
            S = webread("https://archive-api.open-meteo.com/v1/era5?latitude=38.8921&longitude=-77.0241&start_date=2022-01-01&end_date=2022-07-13&daily=temperature_2m_max&timezone=America%2FNew_York&temperature_unit=fahrenheit&windspeed_unit=mph");

app.data = S.daily.temperature_2m_max;
plot(app.UIAxes, [S.daily.temperature_2m_max])

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
     
                changingValue = event.Value;
                app.smoothedData = smooth(app.data, changingValue);
                plot(app.UIAxes, app.data)
                hold(app.UIAxes, "on")
                plot(app.UIAxes, app.smoothedData)
                hold(app.UIAxes, "off")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Temperature')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end