classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        RollTextArea4       matlab.ui.control.TextArea
        NumberofRollsPanel  matlab.ui.container.Panel
        RollTextArea5       matlab.ui.control.TextArea
        RollTotal           matlab.ui.container.Panel
        Die2Panel           matlab.ui.container.Panel
        Roll2TextTextArea   matlab.ui.control.TextArea
        Die1Panel           matlab.ui.container.Panel
        Roll1TextTextArea   matlab.ui.control.TextArea
        RollButton          matlab.ui.control.Button
        RollTextArea        matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numRolls = 0; % Description
        dieTotalHistory = zeros(11,1) % Description
        possibleDieTotal = [2:12] % Description

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            die1 = randi([1 6]);
            die2 = randi([1 6]);
            rollTotal = die1 + die2;
            app.numRolls = app.numRolls + 1;
            
            app.Roll1TextTextArea.Value = num2str(die1);
            app.Roll2TextTextArea.Value = num2str(die2);
            app.RollTotal.Value = num2str(rollTotal);
            app.NumberofRollsPanel.Value = num2str(app.numRolls);
            
            app.dieTotalHistory(rollTotal-1) = app.dieTotalHistory(rollTotal-1) + 1;
            
            bar(app.UIAxes, app.possibleDieTotal, app.dieTotalHistory);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.RollTextArea = uitextarea(app.UIFigure);
            app.RollTextArea.Value = {'Roll the Dice'};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.RollButton = uibutton(app.Panel, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.Die1Panel = uipanel(app.Panel);
            app.Die1Panel.Title = 'Die 1';
            app.Roll1TextTextArea = uitextarea(app.Die1Panel);
            app.Roll1TextTextArea.Value = {'#'};
            app.Die2Panel = uipanel(app.Panel);
            app.Die2Panel.Title = 'Die 2';
            app.Roll2TextTextArea = uitextarea(app.Die2Panel);
            app.Roll2TextTextArea.Value = {'#'};
            app.RollTotal = uipanel(app.Panel);
            app.RollTotal.Title = 'Total';
            app.NumberofRollsPanel = uipanel(app.Panel);
            app.NumberofRollsPanel.Title = 'Number of Rolls';
            app.RollTextArea5 = uitextarea(app.NumberofRollsPanel);
            app.RollTextArea5.Value = {'#'};
            app.RollTextArea4 = uitextarea(app.Panel);
            app.RollTextArea4.Value = {'#'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = '=';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end